classdef predict_trajectory < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        RunallButton                   matlab.ui.control.Button
        StateNoiseSliderLabel          matlab.ui.control.Label
        StateNoiseSlider               matlab.ui.control.Slider
        RunonestepButton               matlab.ui.control.Button
        ObservationNoiseSliderLabel    matlab.ui.control.Label
        ObservationNoiseSlider         matlab.ui.control.Slider
        SelectDirectionDropDownLabel   matlab.ui.control.Label
        SelectDirectionDropDown        matlab.ui.control.DropDown
        UIAxes_3                       matlab.ui.control.UIAxes
        NumberofneuronsEditFieldLabel  matlab.ui.control.Label
        NumberofneuronsEditField       matlab.ui.control.NumericEditField
        ResetButton                    matlab.ui.control.Button
        SortbyPDCheckBox               matlab.ui.control.CheckBox
        RecordNewSpikesButton          matlab.ui.control.Button
        AEditFieldLabel                matlab.ui.control.Label
        AEditField                     matlab.ui.control.NumericEditField
        EditField                      matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        EditField_3                    matlab.ui.control.NumericEditField
        EquationsLabel                 matlab.ui.control.Label
        EquationsLabel_2               matlab.ui.control.Label
        MuLabel                        matlab.ui.control.Label
        PhiLabel                       matlab.ui.control.Label
        MuLabel_2                      matlab.ui.control.Label
        PhiLabel_2                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        datafile; % Description
        task = 0; % Description
        current_timestep = 1;  % Description
        all_mu_vals;
        all_phi_vals;
        dia;
    end
    
    methods (Access = private)
        
        function ret = getAmat(app)
            ret = [app.AEditField.Value, app.EditField.Value; ...
                app.EditField_2.Value, app.EditField_3.Value];
        end
        
        
        %TODO:
        
        function [arm_vel, mu_vel, dt, pos_est, arm_pos] = filter_neurons(app)
            %TODO: set aside training/test data
            A = getAmat(app);
            Q_mag = app.StateNoiseSlider.Value;
            Q = [1 0;0 1] .* Q_mag;
            spikes = app.datafile{1};
            dt = app.datafile{2};
            cosine_fit = app.datafile{3};
            arm_vel = app.datafile{4};
            app.datafile{9} = zeros(2,2,size(spikes,2));
            % B regression coefficients
            B = cosine_fit.Bs;
            Bnorm = cosine_fit.Bnorm; %scale factor does weird things to the distributions
            sigma = cosine_fit.sigma; %this represents the variance in the firing
            % R NxN matrix of noise distribution from regression
            R_mag = app.ObservationNoiseSlider.Value;
            R = sigma.*R_mag; %not sure that we need to multiply by R_mag here since it already alters firing
            sm = 2; %smoothing value
            %Mean of velocity initial time step, dimension x 1, initialize at zero
            mu = zeros(2,size(spikes,2)); %this was size(spikes,2) before but that wasn't right
            %mu(:) = (zeros(2, 1));
            %Covariance matrix of velocity initial time step
            phi = zeros(2, 2);
            
            %for i = 1:length(arm_vel)
            arm_pos(1,:) = [0,0];
            for j = 1:size(arm_vel, 1)
                arm_pos(j+1,1) = arm_pos(j,1)+arm_vel(j,1)*dt;
                arm_pos(j+1,2) = arm_pos(j,2)+arm_vel(j,2)*dt;
            end
            arm_pos = arm_pos(2:end,:);
            arm_pos = arm_pos/max(max(abs(arm_pos))); %normalizes arm_pos
            %end
            
            %for traj = 1:size(spikes, 2)
            for j = 1:size(spikes, 2)
                if mod(sm, 2) == 0
                    mnarr = spikes(:, max(j-sm/2, 1):min(j+sm/2, size(spikes, 2)));
                else
                    mnarr = [mean(spikes(:, max(j-floor(sm/2)-1, 1):max(j-floor(sm/2), 1)), 2) ...
                        spikes(:, max(j-floor(sm/2), 1):min(j+floor(sm/2), size(spikes, 2))) ...
                        mean(spikes(:, min(j+floor(sm/2), size(spikes, 2)):min(j+floor(sm/2)+1, size(spikes, 2))), 2)];
                end
                %smooth spike rates
                %as well but it seems like it should...
                sm_f0(:, j) = mean(mnarr, 2)/dt-cosine_fit.b0s;
            end
            
            sm_f0 = spikes;
            
            pos_est(:,1) = [0;0];
            phi_vals = cell(1, size(spikes, 2));
            phi_vals(:) = {[0 0;0 0]};
            mu_est_arr = zeros(2,size(spikes,2));
            phi_est_arr = cell(1, size(spikes, 2));
            phi_est_arr(:) = {[0 0;0 0]};
            
            for t = 1:size(spikes, 2)
                % one step prediction
                mu_est = A * mu(:, t);
                mu_est_arr(:, t) = mu_est;
                phi_est = A * phi * A' + Q;
                phi_est_arr{t} = phi_est;
                pred_vals{t} = {mu_est, phi_est}; %this is to compare the model estimates and Kalman estimates
                % Kalman Gain
                K = phi * B' * inv(B*phi*B'+R);
                Knorm = phi * Bnorm' * inv(Bnorm*phi*Bnorm'+R);
                app.datafile{9}(:,:,t) = phi;
                %need to also add in prediction based on recording
                %TODO: do we need to do OLE with normalized version? 
                P{t} = (B'*R^(-1)*B)^(-1)*B'*R^(-1);
                mu_meas_est(t, :) = P{t}*sm_f0(:, t);
                rec_est{t} = {P{t}*sm_f0(:,t), sigma};
                % Update the state estimate.
                %mu_norm(:, t+1) = mu_est + Knorm * (sm_f0(:,t) - Bnorm*mu_est); %TODO: is mu recalculation necessary?
                mu_est = mu_est + K * (sm_f0(:,t) - B*mu_est);
                % update covariance estimation.
                phi_norm{t} = phi_est - Knorm * Bnorm * phi_est; 
                phi_est = phi_est - K * B * phi_est;
                
                mu(:, t+1) = mu_est;
                pos_est(:,t+1) = pos_est(:,t)+mu_est*dt;
                phi = phi_est;
                phi_vals{t} = phi;
                if(abs(pos_est(1,t+1)) > 1 || abs(pos_est(2,t+1)) > 1)
                    break;
                end
            end
            pos_est = pos_est(:, 2:end)';
            mu_vel = mu(:, 2:end)';
            
            phi_meas = cell(1, size(spikes, 2));
            phi_meas(:) = {[R_mag 0;0 R_mag]};
            
            %set values for whole program
            app.all_mu_vals{1} = mu_est_arr'; %one step update
            app.all_mu_vals{3} = mu_vel; %final combined values
            app.all_mu_vals{2} = mu_meas_est;  %measurement update
            app.all_phi_vals{1} = phi_est_arr;
            app.all_phi_vals{3} = phi_norm; %phi_vals;
            app.all_phi_vals{2} = phi_meas;
            %             elseif app.task == 0
            %                 disp('You have not yet set the task');
            %             end
        end
        
        %this functions finds the user selected direction and assigns position coordinates based on that
        function [dir, dt] = find_direction(app)
            c = find_dropdown(app);
            if c(1)
                pos_fin = [0,1];
            elseif c(2)
                pos_fin = [1,1];
            elseif c(3)
                pos_fin = [1,0];
            elseif c(4)
                pos_fin = [1,-1];
            elseif c(5)
                pos_fin = [0, -1];
            elseif c(6)
                pos_fin = [-1, -1];
            elseif c(7)
                pos_fin = [-1, 0];
            elseif c(8)
                pos_fin = [-1, 1];
            end
            
            %Q_mag = app.StateNoiseSlider.Value;
            dt = 0.1;
            idx = [0:dt:2*pi];
            a = -(pos_fin(1));
            dir(:,1) = a*(cos(idx)-1);
            dir(:,1) = dir(:,1) + (rand(size(dir,1), 1)-0.5);
            a = -(pos_fin(2));
            dir(:,2) = a*(cos(idx)-1);
            dir(:,2) = dir(:,2) + (rand(size(dir,1), 1)-0.5);
            %need to write code that produces raster data from this
        end
        
        %this function specifies logical values based on user selection
        function [c] = find_dropdown(app)
            c(1) = strcmp(app.SelectDirectionDropDown.Value,'Up-Up');
            c(2) = strcmp(app.SelectDirectionDropDown.Value,'Up-Right');
            c(3) = strcmp(app.SelectDirectionDropDown.Value,'Right-Right');
            c(4) = strcmp(app.SelectDirectionDropDown.Value,'Down-Right');
            c(5) = strcmp(app.SelectDirectionDropDown.Value,'Down-Down');
            c(6) = strcmp(app.SelectDirectionDropDown.Value,'Down-Left');
            c(7) = strcmp(app.SelectDirectionDropDown.Value,'Left-Left');
            c(8) = strcmp(app.SelectDirectionDropDown.Value,'Up-Left');
        end
        
        function [spikes, cosine_fit] = make_raster(app)
            %make random cosine tuned cells
            fr = 10/pi; %maximal firing rate per bin
            num_cells = app.datafile{5}; %total number of cells - user selected
            arm_vel = app.datafile{4}; %actual velocity - from trajectories
            vel = 30;%scaling factor
            x_idx = rand(num_cells, 1)-0.5; %this selects some random # from -0.5 to 0.5
            y_idx = rand(num_cells, 1)-0.5;
            p_vel(:,1) = x_idx*vel; %creates random preferred velocities by multiplying the random number by the max velocity
            p_vel(:,2) = y_idx*vel;
            cosine_fit.Bs = p_vel; %sets the B terms equal to our derived preferred directions
            cosine_fit.Bnorm = cosine_fit.Bs;%[x_idx, y_idx]; 
            cosine_fit.b0s = zeros(num_cells, 1); %set baseline firing to zero because it was messing the predictions up
            %fr*rand(num_cells, 1)-0.5; %sets baseline firing to some random amount from -0.5 to 0.5 multiplied by fr
            for i = 1:length(arm_vel) %total time of trajectory
                for j = 1:length(p_vel) %total number of cells
                    angle_B = (atan(p_vel(j,2)/p_vel(j,1))); %solves for the preferred angle using arctan
                    if p_vel(j,1) < 0
                        angle_B = angle_B + pi; %have to make corrections based on MATLAB operations
                    elseif p_vel(j,2) < 0
                        angle_B = angle_B + 2*pi;
                    end
                    p_vel_sort(j) = angle_B; %going to use this to sort by preferred direction
                    angle_vel = atan(arm_vel(i,2)/arm_vel(i,1)); %finds angle of actual reach
                    if arm_vel(i,1) < 0
                        angle_vel = angle_vel + pi;
                    elseif arm_vel(i,2) < 0
                        angle_vel = angle_vel + 2*pi;
                    end
                    R_mag = app.ObservationNoiseSlider.Value; %noise of recording: will alter rasters
                    noise = normrnd(0, R_mag); %creates noise that has variance equal to the defined observation noise
                    spikes(j,i) = round(fr*(cosine_fit.b0s(j) + (cos(angle_B - angle_vel))+noise)); %put as (j,i) so the array is the same structure as previous data
                end
            end
            if app.SortbyPDCheckBox.Value == 1 %will sort spikes if checked
                [~, p_order] = sort(p_vel_sort);
                spikes = spikes(p_order,:);
                p_vel = p_vel(p_order,:);
            end
            cosine_fit.Bs = p_vel; %sets the B terms equal to our derived preferred directions
            cosine_fit.b0s = zeros(num_cells, 1); %set baseline firing to zero because it was messing the predictions up
            var_cos = var(spikes,0,2);
            cosine_fit.sigma = diag(var_cos); %assuming cells fire independently i.e. no correlation
            
        end
        
        function plot_dist(app, mu_cell, phi_cell)
            %define colors for the signal, model, and combined distributions
            redcolors = [184 6 0; 229 136 125]/255; %dark, then light
            bluecolors = [12 48 181; 134 154 219]/255;
            greencolors = [35 97 15; 112 176 83]/255;
            colors = {redcolors, bluecolors, greencolors};
            linestyle = {':', ':', '-'};
            cla(app.UIAxes_3);
            for i=1:3
                mu = mu_cell{i};
                phi = phi_cell{i};
                ell_cell = calc_ellpr(app, mu, phi);
                hold(app.UIAxes_3, 'on');
                h(i) = plot(app.UIAxes_3, ell_cell{1}(:, 1), ell_cell{1}(:, 2), linestyle{i}, 'Linewidth', 3, 'Color', colors{i}(1, :));
                plot(app.UIAxes_3, ell_cell{2}(:, 1), ell_cell{2}(:, 2), linestyle{i}, 'Linewidth', 3, 'Color', colors{i}(2, :));
                max_max(i) = max(max(ell_cell{1}));
            end
            legend(app.UIAxes_3, h, {'One step', 'Measurement', 'Final'}, 'Location', 'southeast', 'FontSize', 10);
            if app.current_timestep == 1
                app.dia = 2*max(max_max);
            end
            axis(app.UIAxes_3, [-app.dia app.dia -app.dia app.dia]); %'equal'
            xlabel(app.UIAxes_3, 'V_x'); 
            ylabel(app.UIAxes_3, 'V_y'); 
            
        end
        
        function ellipse_pr = calc_ellpr(app, mu, phi)
            %calculate F, midpt, sd, inds for sd1 and sd2
            %call function to return ellipse array
            
            %should use mu + 2* sqrt of variance to determine limits
            %choose the range of the plot
            varmat = sqrt(phi)*2.5; %calculate the std dev matrix
            nsamples = 200;
            x = (mu(1)-varmat(1, 1)):(2*varmat(1, 1)/nsamples):(mu(1)+varmat(1, 1));
            y = (mu(2)-varmat(2, 2)):(2*varmat(2, 2)/nsamples):(mu(2)+varmat(2, 2));
            [X,Y] = meshgrid(x,y);
            %calculate multivariate normal distribution
            try
                F = mvnpdf([X(:) Y(:)],mu,phi);
            catch
                disp(phi);
                if phi(2)<0
                    phi(2:3) = 0;
                else
                    phi(2) = phi(3); %sometimes there are rounding errors
                end
                F = mvnpdf([X(:) Y(:)],mu,phi);
            end
            F = reshape(F,length(y),length(x));
            %find high middle point
            [midpt, idx] = max(F(:));
            [row, col] = ind2sub(size(F), idx);
            %find 1, 2 std dev
            sdev = std(F(:, col));
            %discrimination value = how selectively it finds circle
            discval = midpt/50;
            sd1inds = not(abs(sign(sign(midpt-sdev-discval - F) + sign(midpt-sdev+discval - F))));
            sd2inds = not(abs(sign(sign(midpt-2*sdev-discval - F) + sign(midpt-2*sdev+discval - F))));
            
            %assign values and center on zero
            %and normalize back to mu location
            ell_vals1 = (calc_ell(app, sd1inds)-[row,col]).*([range(x) range(y)]/nsamples) + mu;
            ell_vals2 = (calc_ell(app, sd2inds)-[row,col]).*([range(x) range(y)]/nsamples) + mu;
            
            ellipse_pr = {ell_vals1, ell_vals2};
        end
        
        function ellipse_arr = calc_ell(app, inds)
            %switch these values to the correct coordinate system for normal plotting
            %and normalize to the correct scale
            [row, col]=find(inds>0);
            %for each row, find the extreme columns
            vals1 = []; vals2 = [];
            for x=1:length(col)
                xidx = find(col==col(x));
                vals1(end+1, :) = [row(min(xidx)), col(x)];
                vals2(end+1, :) = [row(max(xidx)), col(x)];
            end
            try
                ellipse_arr = [[vals1(:, 1); flipud(vals2(:, 1)); vals1(1, 1)], ...
                    [vals1(:, 2); flipud(vals2(:, 2)); vals1(1, 2)]];
            catch
                disp(vals1)
                disp(vals2)
            end
        end
    
        function write_equations(app)  
            mu = char(956); 
            phi = char(934); 
            sub_t = char(8348); 
            sub_t1 = [char(8348) char(8331) char(8321)]; 
            sup_minus = char(8315); 
            sup_T = char(7488); 
            sup_inv = [sup_minus char(185)]; 
            eq1 = [mu sub_t sup_minus ' = A' mu sub_t1];
            eq2 = [phi sub_t sup_minus ' = A' phi sub_t1 'A' sup_T ' + Q']; 
            eq3 = ['K' sub_t ' = ' phi sub_t sup_minus 'B' sup_T '(B' phi sub_t sup_minus 'B' sup_T ' + R)' sup_inv]; 
            eq4 = [mu sub_t ' = ' mu sub_t sup_minus ' + K' sub_t '(f' sub_t ' - B' mu sub_t sup_minus ')']; 
            eq5 = [phi sub_t ' = ' phi sub_t sup_minus ' - K' sub_t 'B' phi sub_t sup_minus]
            text = sprintf('%s\n%s\n\n%s\n\n%s\n%s', eq1, eq2, eq3, eq4, eq5);
            app.EquationsLabel.Text = text; 
            
        end


    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %load('ps5_data_2D.mat'); %this is currently using the data from HW
            app.current_timestep = 1;
            [arm_vel, dt] = find_direction(app);
            num_cells = app.NumberofneuronsEditField.Value;
            app.datafile{5} = num_cells;
            app.datafile{4} = arm_vel;
            [spikes, cosine_fit] = make_raster(app);
            disp(cosine_fit);
            app.datafile = {spikes, dt, cosine_fit, arm_vel, num_cells};
            
            app.write_equations();
            app.MuLabel.Text = sprintf('%s\n%s', '0', '0'); 
            app.PhiLabel.Text = sprintf('%s  %s\n%s  %s', '0', '0', '0', '0'); 
            
            %app.task = 1;
            imagesc(app.UIAxes, spikes); %spikes{2}
            c = colorbar(app.UIAxes, 'westoutside');
            set(c, 'Ticks',[c.Limits(1), c.Limits(2)],...
             'TickLabels',{'Low', 'High'}, 'FontSize', 12); 
            set(app.UIAxes, 'xtick', [], 'ytick', []);
            c.Label.String = 'Spike rate'; 
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Neurons');
            set(app.UIAxes, 'XLim', [1 size(spikes, 2)], 'YLim', [1 size(spikes, 1)])
            
            set(app.UIFigure,'Name','Kalman Filtering');
            %imshow('Equations.jpg','Parent',app.EquationFigure);
        end

        % Value changed function: NumberofneuronsEditField
        function NumberofneuronsEditFieldValueChanged(app, event)
            num_cells = app.NumberofneuronsEditField.Value;
            app.datafile{5} = num_cells;
            %if app.task == 1
            [spikes, cosine_fit] = make_raster(app);
            app.datafile{1} = spikes;
            app.datafile{3} = cosine_fit;
            imagesc(app.UIAxes, spikes); %spikes{2}
            set(app.UIAxes, 'xtick', [], 'ytick', []);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Neurons');
            set(app.UIAxes, 'XLim', [1 size(spikes, 2)], 'YLim', [1 size(spikes, 1)])
            %end
            
        end

        % Value changed function: ObservationNoiseSlider
        function ObservationNoiseSliderValueChanged(app, event)
            R_mag = app.ObservationNoiseSlider.Value;
            app.datafile{8} = R_mag;
            %if app.task == 1
            [spikes, cosine_fit] = make_raster(app);
            app.datafile{1} = spikes;
            app.datafile{3} = cosine_fit;
            imagesc(app.UIAxes, spikes); %spikes{2}
            set(app.UIAxes, 'xtick', [], 'ytick', []);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Neurons');
            set(app.UIAxes, 'XLim', [1 size(spikes, 2)], 'YLim', [1 size(spikes, 1)])
            %end
        end

        % Button pushed function: RecordNewSpikesButton
        function RecordNewSpikesButtonPushed(app, event)
            
            num_cells = app.NumberofneuronsEditField.Value;
            app.datafile{5} = num_cells;
            R_mag = app.ObservationNoiseSlider.Value;
            app.datafile{8} = R_mag;
            [spikes, cosine_fit] = make_raster(app);
            app.datafile{1} = spikes;
            app.datafile{3} = cosine_fit;
            imagesc(app.UIAxes, spikes);
            c = colorbar(app.UIAxes, 'westoutside');
            set(c, 'Ticks',[c.Limits(1), c.Limits(2)],...
             'TickLabels',{'Low', 'High'}); 
        end

        % Button pushed function: ResetButton
        function ResetAll(app, event)
            cla(app.UIAxes_2)
            cla(app.UIAxes_3)
            app.current_timestep = 1;
            % Update phi and mu values in GUI
            %             app.MuXText.Value = 0;
            %             app.MuYText.Value = 0;
            app.MuLabel.Text = sprintf('%s\n%s', '0', '0'); 
            app.PhiLabel.Text = sprintf('%s  %s\n%s  %s', '0', '0', '0', '0'); 
            
            %             app.PhiXVarText.Value = 0;
            %             app.PhiXYCovText.Value = 0;
            %             app.PhiYXCovText.Value = 0;
            %             app.PhiYVarText.Value = 0;
        end

        % Button pushed function: RunallButton
        function RunallButtonPushed(app, event)
            [arm_vel, mu_vel, dt, pos_est, arm_pos] = filter_neurons(app);
            app.datafile{10} = mu_vel;
            %plot the actual arm positions and the predicted
            axis(app.UIAxes_2, [-1 1 -1 1])
            hold(app.UIAxes_2, 'off');
            %max_val = max((max(abs(arm_pos))+0.001)); %to normalize to 1
            plot(app.UIAxes_2, arm_pos(:,1), arm_pos(:,2), 'bo-', 'LineWidth', 2);
            hold(app.UIAxes_2, 'on');
            %max_val = max(max(abs(pos_est)));
            plot(app.UIAxes_2, pos_est(:,1), pos_est(:,2), ':ro', 'LineWidth', 2);
            c = find_dropdown(app);
            if c(1) || c(2) || c(3) || c(8)
                leg_loc = 'southeast';
            else
                leg_loc = 'northeast';
            end
            legend(app.UIAxes_2, {'Actual Reach', 'Predicted Reach'}, 'Location', leg_loc, 'FontSize', 10);
            
            % Update phi and mu values in GUI
            mu_x_val = round(app.datafile{10}(app.current_timestep, 1), 2);
            mu_y_val = app.datafile{10}(app.current_timestep, 2);
            app.MuLabel.Text = sprintf('%s\n%s', num2str(mu_x_val), num2str(mu_y_val, 2)); 
            
            %             app.MuXText.Value = app.datafile{10}(app.current_timestep, 1);
            %             app.MuYText.Value = app.datafile{10}(app.current_timestep, 2);
            %            
            %phi value is one behind in data so we have to add 1 to plot correct value
            phi_xx = app.datafile{9}(1,1,app.current_timestep+1);
            phi_xy = app.datafile{9}(1,2,app.current_timestep+1);
            phi_yx = app.datafile{9}(2,1,app.current_timestep+1);
            phi_yy = app.datafile{9}(2,2,app.current_timestep+1);
            app.PhiLabel.Text = sprintf('%s  %s\n%s  %s', num2str(phi_xx, 2), num2str(phi_xy, 2), num2str(phi_yx, 2), num2str(phi_yy, 2)); 
            
        end

        % Button pushed function: RunonestepButton
        function RunonestepButtonPushed(app, event)
            %TODO: this is not currently functioning as of updates to Run all/up-up button option
            %for run all, all at once and here, one point at a time (each time button
            %is pushed) and increment current_timestep
            if app.current_timestep == 1
                [arm_vel, mu_vel, dt, pos_est, arm_pos] = filter_neurons(app); %shouldn't repeat this every time as that can mess things up
                app.datafile{10} = mu_vel;
                app.datafile{4} = arm_vel;
                app.datafile{6} = pos_est;
                app.datafile{7} = arm_pos;
            else
                arm_vel = app.datafile{4};
                pos_est = app.datafile{6};
                arm_pos = app.datafile{7};
            end
            %plot the actual arm positions and the predicted
            axis(app.UIAxes_2, [-1 1 -1 1])
            %hold(app.UIAxes_2, 'off');
            %max_val = max((max(abs(arm_pos))+0.001)); %to normalize to 1 - may be a better way to go about this
            if size(arm_pos, 1) > app.current_timestep
                plot(app.UIAxes_2, arm_pos(1:app.current_timestep, 1), arm_pos(1:app.current_timestep, 2), 'bo-', 'LineWidth', 2);
            end
            hold(app.UIAxes_2, 'on');
            %max_val = max(max(abs(pos_est)));
            if size(pos_est, 1) > app.current_timestep
                plot(app.UIAxes_2, pos_est(1:app.current_timestep, 1), pos_est(1:app.current_timestep, 2), ':ro', 'LineWidth', 2);


            % Update phi and mu values in GUI
            mu_x_val = app.datafile{10}(app.current_timestep, 1);
            mu_y_val = app.datafile{10}(app.current_timestep, 2);
            app.MuLabel.Text = sprintf('%s\n%s', num2str(mu_x_val, 2), num2str(mu_y_val, 2)); 
            
            phi_xx = app.datafile{9}(1,1,app.current_timestep+1);
            phi_xy = app.datafile{9}(1,2,app.current_timestep+1);
            phi_yx = app.datafile{9}(2,1,app.current_timestep+1);
            phi_yy = app.datafile{9}(2,2,app.current_timestep+1);
            app.PhiLabel.Text = sprintf('%s  %s\n%s  %s', num2str(phi_xx, 2), num2str(phi_xy, 2), num2str(phi_yx, 2), num2str(phi_yy, 2)); 
            %             app.MuXText.Value = app.datafile{10}(app.current_timestep, 1);
            %             app.MuYText.Value = app.datafile{10}(app.current_timestep, 2);
            
            %             app.PhiXVarText.Value = app.datafile{9}(1,1,app.current_timestep);
            %             app.PhiXYCovText.Value = app.datafile{9}(1,2,app.current_timestep);
            %             app.PhiYXCovText.Value = app.datafile{9}(2,1,app.current_timestep);
            %             app.PhiYVarText.Value = app.datafile{9}(2,2,app.current_timestep);
            
            %plot new distributions
            %set the means and variances for this time step
            mu_cell = {app.all_mu_vals{1}(app.current_timestep, :), ...
                app.all_mu_vals{2}(app.current_timestep, :), ...
                app.all_mu_vals{3}(app.current_timestep, :)};
            phi_cell = {app.all_phi_vals{1}{app.current_timestep}, ...
                app.all_phi_vals{2}{app.current_timestep}, ...
                app.all_phi_vals{3}{app.current_timestep}};
            app.plot_dist(mu_cell, phi_cell);
            end
            c = find_dropdown(app);
            if c(1) || c(2) || c(3) || c(8)
                leg_loc = 'southeast';
            else
                leg_loc = 'northeast';
            end
            legend(app.UIAxes_2, {'Actual Reach', 'Predicted Reach'}, 'FontSize', 10, 'Location', leg_loc);
            if app.current_timestep < size(arm_vel, 1)
                app.current_timestep = app.current_timestep + 1;
            end
        end

        % Value changed function: SelectDirectionDropDown
        function SelectDirectionDropDownValueChanged(app, event)
            cla(app.UIAxes_2)
            cla(app.UIAxes_3)
            app.current_timestep = 1;
            dir = app.SelectDirectionDropDown.Value;
            %load('ps5_data_2D.mat'); %this is currently using the data from HW
            [arm_vel, dt] = find_direction(app);
            %num_cells = 100; %want to let user control this
            num_cells = app.NumberofneuronsEditField.Value;
            app.datafile{5} = num_cells;
            app.datafile{4} = arm_vel;
            R_mag = app.ObservationNoiseSlider.Value;
            app.datafile{8} = R_mag;
            [spikes, cosine_fit] = make_raster(app);
            disp(cosine_fit);
            app.datafile = {spikes, dt, cosine_fit, arm_vel, num_cells}; %might want to change this in general
            imagesc(app.UIAxes, spikes);
        end

        % Value changed function: StateNoiseSlider
        function StateNoiseSliderValueChanged(app, event)
            Q_mag = app.StateNoiseSlider.Value;
            %             [arm_vel] = find_direction(app);
            %             app.datafile{4} = arm_vel;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Data')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Estimation')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.RunallButton = uibutton(app.UIFigure, 'push');
            app.RunallButton.ButtonPushedFcn = createCallbackFcn(app, @RunallButtonPushed, true);
            app.RunallButton.Text = 'Run all';
            app.StateNoiseSliderLabel = uilabel(app.UIFigure);
            app.StateNoiseSliderLabel.Text = 'State Noise';
            app.StateNoiseSlider = uislider(app.UIFigure);
            app.StateNoiseSlider.Limits = [0.01 10];
            app.StateNoiseSlider.MajorTicks = [];
            app.StateNoiseSlider.MajorTickLabels = {};
            app.StateNoiseSlider.ValueChangedFcn = createCallbackFcn(app, @StateNoiseSliderValueChanged, true);
            app.StateNoiseSlider.MinorTicks = [];
            app.StateNoiseSlider.Value = 1;
            app.RunonestepButton = uibutton(app.UIFigure, 'push');
            app.RunonestepButton.ButtonPushedFcn = createCallbackFcn(app, @RunonestepButtonPushed, true);
            app.RunonestepButton.Text = 'Run one step';
            app.ObservationNoiseSliderLabel = uilabel(app.UIFigure);
            app.ObservationNoiseSliderLabel.Text = 'Observation Noise';
            app.ObservationNoiseSlider = uislider(app.UIFigure);
            app.ObservationNoiseSlider.Limits = [0.1 10];
            app.ObservationNoiseSlider.MajorTicks = [];
            app.ObservationNoiseSlider.MajorTickLabels = {};
            app.ObservationNoiseSlider.ValueChangedFcn = createCallbackFcn(app, @ObservationNoiseSliderValueChanged, true);
            app.ObservationNoiseSlider.MinorTicks = [];
            app.ObservationNoiseSlider.Value = 1;
            app.SelectDirectionDropDownLabel = uilabel(app.UIFigure);
            app.SelectDirectionDropDownLabel.Text = 'Select Direction';
            app.SelectDirectionDropDown = uidropdown(app.UIFigure);
            app.SelectDirectionDropDown.Items = {'Up-Up', 'Up-Right', 'Right-Right', 'Down-Right', 'Down-Down', 'Down-Left', 'Left-Left', 'Up-Left'};
            app.SelectDirectionDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDirectionDropDownValueChanged, true);
            app.SelectDirectionDropDown.Value = 'Up-Up';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Distribution')
            xlabel(app.UIAxes_3, 'V_x')
            ylabel(app.UIAxes_3, 'V_y')
            app.NumberofneuronsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofneuronsEditFieldLabel.Text = 'Number of neurons';
            app.NumberofneuronsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofneuronsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofneuronsEditFieldValueChanged, true);
            app.NumberofneuronsEditField.Value = 100;
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAll, true);
            app.ResetButton.Text = 'Reset';
            app.SortbyPDCheckBox = uicheckbox(app.UIFigure);
            app.SortbyPDCheckBox.Text = 'Sort by PD';
            app.RecordNewSpikesButton = uibutton(app.UIFigure, 'push');
            app.RecordNewSpikesButton.ButtonPushedFcn = createCallbackFcn(app, @RecordNewSpikesButtonPushed, true);
            app.RecordNewSpikesButton.Text = 'Record New Spikes';
            app.AEditFieldLabel = uilabel(app.UIFigure);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.UIFigure, 'numeric');
            app.AEditField.Value = 1;
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.Value = 1;
            app.EquationsLabel = uilabel(app.UIFigure);
            app.EquationsLabel.Text = 'Equations';
            app.EquationsLabel_2 = uilabel(app.UIFigure);
            app.EquationsLabel_2.Text = 'Equations';
            app.MuLabel = uilabel(app.UIFigure);
            app.MuLabel.Text = 'Mu';
            app.PhiLabel = uilabel(app.UIFigure);
            app.PhiLabel.Text = 'Phi';
            app.MuLabel_2 = uilabel(app.UIFigure);
            app.MuLabel_2.Text = 'Mu';
            app.PhiLabel_2 = uilabel(app.UIFigure);
            app.PhiLabel_2.Text = 'Phi';
        end
    end

    methods (Access = public)

        % Construct app
        function app = predict_trajectory

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end